classdef uimain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FunctionsettingsPanel          matlab.ui.container.Panel
        saveSpikeTimesAllNeurons       matlab.ui.container.Panel
        MakespikerastersCheckBox       matlab.ui.control.CheckBox
        viewExemplaryChannelsMouseWrapper  matlab.ui.container.Panel
        IncludeUSalignedCheckBox       matlab.ui.control.CheckBox
        NeuroncurationLabel            matlab.ui.control.Label
        initSpikeSorting               matlab.ui.container.Panel
        ResetprobepathCheckBox         matlab.ui.control.CheckBox
        ResetdatapathCheckBox          matlab.ui.control.CheckBox
        SpikesortingLabel              matlab.ui.control.Label
        convertData                    matlab.ui.container.Panel
        ConverttimestampsCheckBox      matlab.ui.control.CheckBox
        ConvertrawspikingdataCheckBox  matlab.ui.control.CheckBox
        PreviewdataCheckBox            matlab.ui.control.CheckBox
        overviewTraces                 matlab.ui.container.Panel
        BehaviorlastdayEditField       matlab.ui.control.NumericEditField
        BehaviorlastdayEditFieldLabel  matlab.ui.control.Label
        ShowbackgroundlinesbehaviorCheckBox  matlab.ui.control.CheckBox
        SavefiguresCheckBox            matlab.ui.control.CheckBox
        batchProcessTrialsExampleIK    matlab.ui.container.Panel
        DebugCheckBox                  matlab.ui.control.CheckBox
        SeefiguresCheckBox             matlab.ui.control.CheckBox
        OverridesavedfiguresCheckBox   matlab.ui.control.CheckBox
        SavefiguresCheckBox_2          matlab.ui.control.CheckBox
        PlotfiguresoldCheckBox         matlab.ui.control.CheckBox
        copyFromNAS                    matlab.ui.container.Panel
        StartfolderEditField           matlab.ui.control.NumericEditField
        StartfolderEditFieldLabel      matlab.ui.control.Label
        EyelidtracesLabel              matlab.ui.control.Label
        LocationDropDown               matlab.ui.control.DropDown
        LocationDropDownLabel          matlab.ui.control.Label
        ProcessonlyEphysmiceCheckBox   matlab.ui.control.CheckBox
        StartsessionEditField          matlab.ui.control.NumericEditField
        StartsessionEditFieldLabel     matlab.ui.control.Label
        SessionnumberEditField         matlab.ui.control.NumericEditField
        SessionnumberLabel             matlab.ui.control.Label
        CodeDropDown                   matlab.ui.control.DropDown
        CodeDropDownLabel              matlab.ui.control.Label
        StartmousenameDropDown         matlab.ui.control.DropDown
        StartmouseDropDownLabel        matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        GeneDropDown                   matlab.ui.control.DropDown
        GeneDropDownLabel              matlab.ui.control.Label
        MouseDropDown                  matlab.ui.control.DropDown
        MouseDropDownLabel             matlab.ui.control.Label
        SinglesessionCheckBox          matlab.ui.control.CheckBox
        StartmousecodeDropDown         matlab.ui.control.DropDown
        CloseButton                    matlab.ui.control.Button
        ConfirmButton                  matlab.ui.control.Button
        ChoosemiceButtonGroup          matlab.ui.container.ButtonGroup
        AllmiceofonegeneButton         matlab.ui.control.ToggleButton
        AllmiceofbothtypesofagenelocationButton  matlab.ui.control.ToggleButton
        SinglemouseButton              matlab.ui.control.ToggleButton
        AllmiceofonegroupgenelocationtypeButton  matlab.ui.control.ToggleButton
        AllmiceButton_2                matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        confirmButtonPushed % Description
    end

    methods (Access = public)
        function appData = getAppData(app, appData)
            appData.enumeration = string(app.ChoosemiceButtonGroup.SelectedObject.Text);
            appData.mouseName = string(app.MouseDropDown.Value);
            appData.mouseCode = string(app.CodeDropDown.Value);
            appData.gene = string(app.GeneDropDown.Value);
            appData.loc = string(app.LocationDropDown.Value);
            appData.type = string(app.TypeDropDown.Value);
            appData.savefigures = app.SavefiguresCheckBox.Value;
            appData.startMouseName = string(app.StartmousenameDropDown.Value);
            appData.startMouseCode = string(app.StartmousecodeDropDown.Value);
            appData.behaviorLastDay = app.BehaviorlastdayEditField.Value;
            appData.showBGlines = app.ShowbackgroundlinesbehaviorCheckBox.Value;
            appData.makeSpikeRasters = app.MakespikerastersCheckBox.Value;
            appData.includeUSaligned = app.IncludeUSalignedCheckBox.Value;
            appData.resetProbePath = app.ResetprobepathCheckBox.Value;
            appData.resetDataPath = app.ResetdatapathCheckBox.Value;
            appData.convertTimeStamps = app.ConverttimestampsCheckBox.Value;
            appData.convertRawSpikingData = app.ConvertrawspikingdataCheckBox.Value;
            appData.previewData = app.PreviewdataCheckBox.Value;
            appData.startSession = app.StartsessionEditField.Value;
            appData.session_n = app.SessionnumberEditField.Value;
            appData.singleSession = app.SinglesessionCheckBox.Value;
            appData.startFolder = app.StartfolderEditField.Value;
            appData.onlySaveFigs = app.SavefiguresCheckBox_2.Value;
            appData.seeFigs = app.SeefiguresCheckBox.Value;
            appData.debugTraces = app.DebugCheckBox.Value;
            appData.overrideFigs = app.OverridesavedfiguresCheckBox.Value;
            appData.plotFigsOld = app.PlotfiguresoldCheckBox.Value;
            appData.processOnlyEphysMice = app.ProcessonlyEphysmiceCheckBox.Value;

            mouseTypes = [defaultMice().type];
            mouseCodes = [defaultMice().code];

            if appData.processOnlyEphysMice
                ephys_mask = arrayfun(@(x) ~isempty(x.ephysdates), defaultMice());
            else
                ephys_mask = true(1, length(mouseTypes)); 
            end

            if appData.enumeration == "Single mouse"
                appData.mousecodes = appData.mouseCode;
                appData.mousetypes = mouseTypes(contains(mouseCodes, appData.mousecodes));
            elseif appData.enumeration == "All mice of one group (gene + location + type)"
                appData.mousecodes = mouseCodes(...
                    contains(mouseTypes, appData.gene) & ...
                    contains(mouseTypes, appData.loc) & ...
                    contains(mouseTypes, appData.type) & ...
                    ephys_mask);
                appData.mousetypes = mouseTypes(contains(mouseCodes, appData.mousecodes));
            elseif appData.enumeration == "All mice of both types of a gene + location"
                appData.mousecodes = mouseCodes(...
                    contains(mouseTypes, appData.gene) & ...
                    contains(mouseTypes, appData.loc) & ...
                    ephys_mask);
                appData.mousetypes = mouseTypes(contains(mouseCodes, appData.mousecodes));
            elseif appData.enumeration == "All mice of one gene"
                appData.mousecodes = mouseCodes(...
                    contains(mouseTypes, appData.gene) & ...
                    ephys_mask);
                appData.mousetypes = mouseTypes(contains(mouseCodes, appData.mousecodes));
            elseif appData.enumeration == "All mice"
                mousecodes = mouseCodes(ephys_mask);
                if appData.startMouseCode == "N/A"
                    appData.startMouseName = app.StartmousenameDropDown.Items(1);
                    appData.startMouseCode = app.StartmousecodeDropDown.Items(1);
                end
                appData.mousecodes = mousecodes(find(logical((mousecodes == appData.startMouseCode)),1) : end);
                appData.mousetypes = mouseTypes(contains(mouseCodes, appData.mousecodes));
            end

            P = IkUtils.getParams();
            if ~appData.singleSession
                appData.sessions = appData.startSession:P.n_sessions;
            else
                appData.sessions = appData.session_n;
            end           

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: MouseDropDown
        function MouseDropDownClicked(app, event)
            types = IkUtils.getParams().types;
            mouseStruct = defaultMice();

            selectedMouse = app.MouseDropDown.Value;
            selectedMouseEnumeration = app.ChoosemiceButtonGroup.SelectedObject.Text;
            if strcmp(selectedMouseEnumeration, 'Single mouse')
                try
                    mouseType = mouseStruct(string(app.MouseDropDown.Items) == selectedMouse).type;
                    t_parts = split(mouseType, ' '); 
                    mouseTypeCC = strjoin(t_parts, '');
                    idx = find([types.full] == mouseTypeCC);
                    app.CodeDropDown.Value = mouseStruct(string(app.MouseDropDown.Items) == selectedMouse).code;
                    app.TypeDropDown.Value = types.type(idx);
                    app.GeneDropDown.Value = types.gene(idx);
                    app.LocationDropDown.Value = types.loc(idx);
                    app.StartmousenameDropDown.Value = "N/A";
                    app.StartmousecodeDropDown.Value = "N/A";
                catch
                    app.CodeDropDown.Value = "N/A";
                    app.TypeDropDown.Value = "N/A";
                    app.GeneDropDown.Value = "N/A";
                    app.LocationDropDown.Value = "N/A";
                    app.StartmousenameDropDown.Value = "N/A";
                    app.StartmousecodeDropDown.Value = "N/A";
                end
            elseif strcmp(selectedMouseEnumeration, 'All mice of one group (gene + location + type)')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
            elseif strcmp(selectedMouseEnumeration, 'All mice of both types of a gene + location')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
            elseif strcmp(selectedMouseEnumeration, 'All mice of one gene')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
                app.LocationDropDown.Value = "N/A";
            else
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
                app.GeneDropDown.Value = "N/A";
                app.LocationDropDown.Value = "N/A";
            end
        end

        % Selection changed function: ChoosemiceButtonGroup
        function ChoosemiceButtonGroupSelectionChanged(app, event)
            selectedMouseEnumeration = app.ChoosemiceButtonGroup.SelectedObject.Text;
            if strcmp(selectedMouseEnumeration, 'All mice of one group (gene + location + type)')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
            elseif strcmp(selectedMouseEnumeration, 'All mice of both types of a gene + location')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
            elseif strcmp(selectedMouseEnumeration, 'All mice of one gene')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
                app.LocationDropDown.Value = "N/A";
            elseif strcmp(selectedMouseEnumeration, 'All mice')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
                app.GeneDropDown.Value = "N/A";
                app.LocationDropDown.Value = "N/A";
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
           % Get app data
            appData = app.getAppData();
            assignin('base', "appData", appData);
            uiresume(app.UIFigure)
%             app.confirmButtonPushed = true;
%             close(app.UIFigure);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % Get app data
            appData = app.getAppData();
            assignin('base', "appData", appData);
            close(app.UIFigure);
        end

        % Clicked callback: CodeDropDown
        function CodeDropDownClicked(app, event)
            types = IkUtils.getParams().types;
            mouseStruct = defaultMice();

            selectedMouse = app.CodeDropDown.Value;
            selectedMouseEnumeration = app.ChoosemiceButtonGroup.SelectedObject.Text;
            if strcmp(selectedMouseEnumeration, 'Single mouse')
                try
                    mouseType = mouseStruct(string(app.CodeDropDown.Items) == selectedMouse).type;
                    t_parts = split(mouseType, ' ');
                    mouseTypeCC = strjoin(t_parts, '');
                    idx = find([types.full] == mouseTypeCC);
                    app.MouseDropDown.Value = mouseStruct(string(app.CodeDropDown.Items) == selectedMouse).name;
                    app.TypeDropDown.Value = types.type(idx);
                    app.GeneDropDown.Value = types.gene(idx);
                    app.LocationDropDown.Value = types.loc(idx);
                    app.StartmousenameDropDown.Value = "N/A";
                    app.StartmousecodeDropDown.Value = "N/A";
                catch
                    app.MouseDropDown.Value = "N/A";
                    app.TypeDropDown.Value = "N/A";
                    app.GeneDropDown.Value = "N/A";
                    app.LocationDropDown.Value = "N/A";
                    app.StartmousenameDropDown.Value = "N/A";
                    app.StartmousecodeDropDown.Value = "N/A";
                end
            elseif strcmp(selectedMouseEnumeration, 'All mice of one group (gene + location + type)')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
            elseif strcmp(selectedMouseEnumeration, 'All mice of both types of a gene + location')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
            elseif strcmp(selectedMouseEnumeration, 'All mice of one gene')
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
                app.LocationDropDown.Value = "N/A";
            else
                app.MouseDropDown.Value = "N/A";
                app.CodeDropDown.Value = "N/A";
                app.TypeDropDown.Value = "N/A";
                app.GeneDropDown.Value = "N/A";
                app.LocationDropDown.Value = "N/A";
            end
        end

        % Clicked callback: StartmousecodeDropDown
        function StartmousecodeDropDownClicked(app, event)
            mouseStruct = defaultMice();
            selectedMouse = app.StartmousecodeDropDown.Value;
            try
                app.StartmousenameDropDown.Value = mouseStruct(string(app.StartmousecodeDropDown.Items) == selectedMouse).name;
            catch
                app.StartmousenameDropDown.Value = "N/A";
            end
        end

        % Value changed function: StartmousenameDropDown
        function StartmousenameDropDownValueChanged(app, event)
            mouseStruct = defaultMice();
            selectedMouse = app.StartmousenameDropDown.Value;
            try
                app.StartmousecodeDropDown.Value = mouseStruct(string(app.StartmousenameDropDown.Items) == selectedMouse).code;
            catch
                app.StartmousecodeDropDown.Value = "N/A";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'uiApp';
            app.ChoosemiceButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoosemiceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosemiceButtonGroupSelectionChanged, true);
            app.ChoosemiceButtonGroup.Title = 'Choose mice';
            app.AllmiceButton_2 = uitogglebutton(app.ChoosemiceButtonGroup);
            app.AllmiceButton_2.Text = 'All mice';
            app.AllmiceButton_2.Value = true;
            app.AllmiceofonegroupgenelocationtypeButton = uitogglebutton(app.ChoosemiceButtonGroup);
            app.AllmiceofonegroupgenelocationtypeButton.Text = 'All mice of one group (gene + location + type)';
            app.SinglemouseButton = uitogglebutton(app.ChoosemiceButtonGroup);
            app.SinglemouseButton.Text = 'Single mouse';
            app.AllmiceofbothtypesofagenelocationButton = uitogglebutton(app.ChoosemiceButtonGroup);
            app.AllmiceofbothtypesofagenelocationButton.Text = 'All mice of both types of a gene + location';
            app.AllmiceofonegeneButton = uitogglebutton(app.ChoosemiceButtonGroup);
            app.AllmiceofonegeneButton.Text = 'All mice of one gene';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.StartmousecodeDropDown = uidropdown(app.UIFigure);
            app.StartmousecodeDropDown.Items = {'20-MI19154-07'};
            app.StartmousecodeDropDown.ClickedFcn = createCallbackFcn(app, @StartmousecodeDropDownClicked, true);
            app.StartmousecodeDropDown.Value = '20-MI19154-07';
            app.SinglesessionCheckBox = uicheckbox(app.UIFigure);
            app.SinglesessionCheckBox.Text = 'Single session';
            app.MouseDropDownLabel = uilabel(app.UIFigure);
            app.MouseDropDownLabel.Text = 'Mouse';
            app.MouseDropDown = uidropdown(app.UIFigure);
            app.MouseDropDown.Items = {'Aurora.1', 'Aurora.2', 'N/A'};
            app.MouseDropDown.ClickedFcn = createCallbackFcn(app, @MouseDropDownClicked, true);
            app.MouseDropDown.Value = 'N/A';
            app.GeneDropDownLabel = uilabel(app.UIFigure);
            app.GeneDropDownLabel.Text = 'Gene';
            app.GeneDropDown = uidropdown(app.UIFigure);
            app.GeneDropDown.Items = {'Shank2', 'Tsc1', 'N/A'};
            app.GeneDropDown.Value = 'N/A';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'WT', 'MUT', 'N/A'};
            app.TypeDropDown.Value = 'N/A';
            app.StartmouseDropDownLabel = uilabel(app.UIFigure);
            app.StartmouseDropDownLabel.Text = 'Start mouse';
            app.StartmousenameDropDown = uidropdown(app.UIFigure);
            app.StartmousenameDropDown.Items = {'Aurora.1', 'Aurora.2', 'N/A'};
            app.StartmousenameDropDown.ValueChangedFcn = createCallbackFcn(app, @StartmousenameDropDownValueChanged, true);
            app.StartmousenameDropDown.Value = 'Aurora.1';
            app.CodeDropDownLabel = uilabel(app.UIFigure);
            app.CodeDropDownLabel.Text = 'Code';
            app.CodeDropDown = uidropdown(app.UIFigure);
            app.CodeDropDown.Items = {'N/A'};
            app.CodeDropDown.ClickedFcn = createCallbackFcn(app, @CodeDropDownClicked, true);
            app.CodeDropDown.Value = 'N/A';
            app.SessionnumberLabel = uilabel(app.UIFigure);
            app.SessionnumberLabel.Text = 'Session number ';
            app.SessionnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionnumberEditField.Value = 1;
            app.StartsessionEditFieldLabel = uilabel(app.UIFigure);
            app.StartsessionEditFieldLabel.Text = 'Start session';
            app.StartsessionEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartsessionEditField.Value = 1;
            app.ProcessonlyEphysmiceCheckBox = uicheckbox(app.UIFigure);
            app.ProcessonlyEphysmiceCheckBox.Text = 'Process only Ephys mice';
            app.ProcessonlyEphysmiceCheckBox.Value = true;
            app.LocationDropDownLabel = uilabel(app.UIFigure);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.UIFigure);
            app.LocationDropDown.Items = {'KO', 'L7', 'N/A'};
            app.LocationDropDown.Value = 'N/A';
            app.FunctionsettingsPanel = uipanel(app.UIFigure);
            app.FunctionsettingsPanel.Title = 'Function settings';
            app.EyelidtracesLabel = uilabel(app.FunctionsettingsPanel);
            app.EyelidtracesLabel.Text = 'Eyelid traces';
            app.copyFromNAS = uipanel(app.FunctionsettingsPanel);
            app.copyFromNAS.Title = 'Process eye traces from NAS';
            app.StartfolderEditFieldLabel = uilabel(app.copyFromNAS);
            app.StartfolderEditFieldLabel.Text = 'Start folder';
            app.StartfolderEditField = uieditfield(app.copyFromNAS, 'numeric');
            app.StartfolderEditField.Limits = [1 Inf];
            app.StartfolderEditField.Value = 1;
            app.batchProcessTrialsExampleIK = uipanel(app.FunctionsettingsPanel);
            app.batchProcessTrialsExampleIK.Title = 'Calculate eyelid traces';
            app.PlotfiguresoldCheckBox = uicheckbox(app.batchProcessTrialsExampleIK);
            app.PlotfiguresoldCheckBox.Text = 'Plot figures old';
            app.SavefiguresCheckBox_2 = uicheckbox(app.batchProcessTrialsExampleIK);
            app.SavefiguresCheckBox_2.Text = 'Save figures';
            app.SavefiguresCheckBox_2.Value = true;
            app.OverridesavedfiguresCheckBox = uicheckbox(app.batchProcessTrialsExampleIK);
            app.OverridesavedfiguresCheckBox.Text = 'Override saved figures';
            app.SeefiguresCheckBox = uicheckbox(app.batchProcessTrialsExampleIK);
            app.SeefiguresCheckBox.Text = 'See figures';
            app.SeefiguresCheckBox.Value = true;
            app.DebugCheckBox = uicheckbox(app.batchProcessTrialsExampleIK);
            app.DebugCheckBox.Text = 'Debug';
            app.overviewTraces = uipanel(app.FunctionsettingsPanel);
            app.overviewTraces.Title = 'Overview traces settings';
            app.SavefiguresCheckBox = uicheckbox(app.overviewTraces);
            app.SavefiguresCheckBox.Text = 'Save figures';
            app.ShowbackgroundlinesbehaviorCheckBox = uicheckbox(app.overviewTraces);
            app.ShowbackgroundlinesbehaviorCheckBox.Text = 'Show background lines behavior';
            app.ShowbackgroundlinesbehaviorCheckBox.Value = true;
            app.BehaviorlastdayEditFieldLabel = uilabel(app.overviewTraces);
            app.BehaviorlastdayEditFieldLabel.Text = 'Behavior last day';
            app.BehaviorlastdayEditField = uieditfield(app.overviewTraces, 'numeric');
            app.BehaviorlastdayEditField.Value = 10;
            app.convertData = uipanel(app.FunctionsettingsPanel);
            app.convertData.Title = 'Convert raw ephys data';
            app.PreviewdataCheckBox = uicheckbox(app.convertData);
            app.PreviewdataCheckBox.Text = 'Preview data';
            app.ConvertrawspikingdataCheckBox = uicheckbox(app.convertData);
            app.ConvertrawspikingdataCheckBox.Text = 'Convert raw spiking data';
            app.ConverttimestampsCheckBox = uicheckbox(app.convertData);
            app.ConverttimestampsCheckBox.Text = 'Convert time stamps';
            app.ConverttimestampsCheckBox.Value = true;
            app.SpikesortingLabel = uilabel(app.FunctionsettingsPanel);
            app.SpikesortingLabel.Text = 'Spike sorting';
            app.initSpikeSorting = uipanel(app.FunctionsettingsPanel);
            app.initSpikeSorting.Title = 'Initiate spike sorting';
            app.ResetdatapathCheckBox = uicheckbox(app.initSpikeSorting);
            app.ResetdatapathCheckBox.Text = 'Reset data path';
            app.ResetdatapathCheckBox.Value = true;
            app.ResetprobepathCheckBox = uicheckbox(app.initSpikeSorting);
            app.ResetprobepathCheckBox.Text = 'Reset probe path';
            app.ResetprobepathCheckBox.Value = true;
            app.NeuroncurationLabel = uilabel(app.FunctionsettingsPanel);
            app.NeuroncurationLabel.Text = 'Neuron curation';
            app.viewExemplaryChannelsMouseWrapper = uipanel(app.FunctionsettingsPanel);
            app.viewExemplaryChannelsMouseWrapper.Title = 'View examplary neurons';
            app.IncludeUSalignedCheckBox = uicheckbox(app.viewExemplaryChannelsMouseWrapper);
            app.IncludeUSalignedCheckBox.Text = 'Include US aligned';
            app.saveSpikeTimesAllNeurons = uipanel(app.FunctionsettingsPanel);
            app.saveSpikeTimesAllNeurons.Title = 'Save spike times';
            app.MakespikerastersCheckBox = uicheckbox(app.saveSpikeTimesAllNeurons);
            app.MakespikerastersCheckBox.Text = 'Make spike rasters';
            app.MakespikerastersCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uimain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end